classdef AcquisitionDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AcquisitionDialogUIFigure       matlab.ui.Figure
        Image                           matlab.ui.control.Image
        SystemReadyLampLabel            matlab.ui.control.Label
        SystemReadyLamp                 matlab.ui.control.Lamp
        MeasurementDetailsPanel         matlab.ui.container.Panel
        RESETAVGButton                  matlab.ui.control.Button
        csvLOADButton                   matlab.ui.control.Button
        TemporalMeasurementFileLabel    matlab.ui.control.Label
        csvOPENButton                   matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        ACQUIREButton                   matlab.ui.control.Button
        NumericValueEditField_2         matlab.ui.control.NumericEditField
        NumericValueEditFieldLabel      matlab.ui.control.Label
        Metadata2EditField              matlab.ui.control.EditField
        Metadata2EditFieldLabel         matlab.ui.control.Label
        NumericValueEditField_1         matlab.ui.control.NumericEditField
        NumericValueEditField_2Label    matlab.ui.control.Label
        Metadata1EditField              matlab.ui.control.EditField
        Metadata1EditFieldLabel         matlab.ui.control.Label
        DescriptionEditField            matlab.ui.control.EditField
        DescriptionEditFieldLabel       matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDownLabel               matlab.ui.control.Label
        SampleEditField                 matlab.ui.control.EditField
        SampleEditFieldLabel            matlab.ui.control.Label
        ReferenceAcquisitionPanel       matlab.ui.container.Panel
        RemoveReferenceButton           matlab.ui.control.Button
        ReferenceButton                 matlab.ui.control.Button
        ReferenceLamp                   matlab.ui.control.Lamp
        ReferenceLampLabel              matlab.ui.control.Label
        RemoveBaselineButton            matlab.ui.control.Button
        BaselineButton                  matlab.ui.control.Button
        BaselineLamp                    matlab.ui.control.Lamp
        BaselineLampLabel               matlab.ui.control.Label
        SubtractBaselineCheckBox        matlab.ui.control.CheckBox
        MeasurementSettingsPanel        matlab.ui.container.Panel
        TimesecEditField                matlab.ui.control.NumericEditField
        TimesecEditFieldLabel           matlab.ui.control.Label
        AverageNumberEditField          matlab.ui.control.NumericEditField
        IntervalsecLabel                matlab.ui.control.Label
        MeasurementCountEditField       matlab.ui.control.NumericEditField
        MeasurementCountEditFieldLabel  matlab.ui.control.Label
        MultiscanSwitch                 matlab.ui.control.Switch
        MultiscanSwitchLabel            matlab.ui.control.Label
        StatusEditField                 matlab.ui.control.EditField
        StatusButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Main app
        numAcq % total number of acquisitions
        matBaseline % Baseline matrix [time stamps; amplitudes]
        matReference % Reference matrix [time stamps; amplitudes]
        processStop % Is Stop-button pressed?
        TcellNew % Newly acquired data cell
    end
    
    methods (Access = private)
        
        function updateMain(app)
            % Call main app's public function
            updateTableRemote(app.MainApp,app.TcellNew);
        end
      
        function addMeasurement(app,fileLoad)
                numAcq = app.numAcq;
                fig = app.AcquisitionDialogUIFigure;
                refOption = false;
                curCol = getSizeofTable(app.MainApp);

                if fileLoad
                    measMat = readWaveformFile(app);
                else
                    measMat = readWaveform(app,refOption);
                end                

                if isempty(measMat)    
                    %errordlg("No Waveform Data");
                    return;
                end
                
                try
                    timeAxis = table2array(measMat(1,2:end));
                    eAmp = table2array(measMat(2:end,2:end));
                    timeStamps = table2array(measMat(2:end,1));
                    clear("measMat");
                catch
                    errordlg("The waveform data are not compatible.");
                    return;
                end


                measNum = size(eAmp,1);
                TcellNew = cell(22,measNum);
                numAcq = numAcq + measNum;
                digitNum = ceil(log10(measNum+1));
                digitNumFormat = strcat('%0',num2str(digitNum),'d');

                for idx = 1:measNum
                    sampleName = app.SampleEditField.Value;
                    description = app.DescriptionEditField.Value;
                    description = strcat(sprintf(digitNumFormat,idx),'_',description);
                    mode = app.ModeDropDown.Value;
                    timeStamp = timeStamps(idx);
                    datetimeValue = datetime(timeStamp, 'ConvertFrom', 'posixtime');
                    datetimeValue.Format = 'yyyy-MM-dd HH:mm:ss.SSS';
                    datetimeValue = char(datetimeValue);

                    md1Des = app.Metadata1EditField.Value;
                    md2Des = app.Metadata2EditField.Value;
                    md1Val = app.NumericValueEditField_1.Value;
                    md2Val = app.NumericValueEditField_2.Value;
                    md3Val = [];
                    md4Val = [];
                    md5Val = [];


                    if ~isempty(md1Des)
                        mdDescription = md1Des;
                    else
                        mdDescription = [];
                    end

                    if ~isempty(md2Des)
                        mdDescription = strcat(mdDescription,',',md2Des);
                    end


                    if isempty(md1Val)
                        md1Val = [];
                    end

                    if isempty(md2Val)
                        md2Val = [];
                    end

                    matBaseline = app.matBaseline;
                    matRefernece = app.matReference;

                    % Baseline subtraction check
                    if app.SubtractBaselineCheckBox.Value

                        if isempty(matBaseline)
                            uialert(fig,'No valid baseline','Acquisition aborted');
                            return;
                        end

                        if ~isempty(matRefernece)
                            try
                                matRefernece(2,:) = matRefernece(2,:) - matBaseline(2,:);
                            catch
                                uialert(fig,'Inconsist Waveform length','Baseline subtraction aborted');
                                return;
                            end
                        end

                        if ~isempty(eAmp)
                            try
                                eAmp(idx,:) = eAmp(idx,:) - matBaseline(2,:);
                            catch
                                uialert(fig,'Inconsist Waveform length','Baseline subtraction aborted');
                                return;
                            end
                        end                    
                    end

                    dsDescription = "Sample"; % dataset description
                    ds1 = [timeAxis;eAmp(idx,:)];

                    if ~isempty(matRefernece)
                        ds2 = [matRefernece(1,:);matRefernece(2,:)];
                        dsDescription = strcat(dsDescription,',',"Reference");
                    else
                        ds2 = [];
                    end

                    ds3 = [];
                    ds4 = [];

                    TcellNew{1,idx} = curCol+idx;
                    TcellNew{2,idx} = sampleName;
                    TcellNew{3,idx} = description;
                    TcellNew{4,idx} = 0; % Instrument profile
                    TcellNew{5,idx} = 0; % User profile
                    TcellNew{6,idx} = datetimeValue; % measurement start time
                    TcellNew{7,idx} = mode; % THz-TDS/THz-Imaging/Transmission/Reflection
                    TcellNew{8,idx} = []; % coordinates

                    TcellNew{9,idx} = mdDescription; % metadata description
                    TcellNew{10,idx} = md1Val;
                    TcellNew{11,idx} = md2Val;
                    TcellNew{12,idx} = md3Val; 
                    TcellNew{13,idx} = md4Val; 
                    TcellNew{14,idx} = md5Val; 

                    TcellNew{15,idx} = []; % not used
                    TcellNew{16,idx} = []; % not used
                    TcellNew{17,idx} = []; % not used

                    TcellNew{18,idx} = dsDescription; % dataset description
                    TcellNew{19,idx} = ds1;
                    TcellNew{20,idx} = ds2;
                    TcellNew{21,idx} = ds3; 
                    TcellNew{22,idx} = ds4; 
                end

                app.TcellNew = TcellNew;
                app.numAcq = numAcq;
        end
        
        function measMat = readWaveform(app,refOption)
            measAverage = app.AverageNumberEditField.Value;
            measTime = app.TimesecEditField.Value;
            measCount = app.MeasurementCountEditField.Value;
            measMode = app.MultiscanSwitch.Value;
            pythonScript = 'getPulse_ml.py';
            progressFile = 'progress.txt';
            delete(progressFile);
            measurementFile = 'measurements.csv';
            runPython = true;
            measMat =[];
            
            if refOption
                command = sprintf('python %s --average %i --count %i &', pythonScript, measAverage, 1);
                mode = "Reference/Baseline measurement";
            else
                if isequal(measMode,'count')
                    command = sprintf('python %s --average %i --count %i &', pythonScript, measAverage, measCount);
                else
                    command = sprintf('python %s --average %i --time %i &', pythonScript, measAverage, measTime);
                end
                mode = "Sample measurement";
            end

            msg = strcat(mode," started");
            app.StatusEditField.Value = msg;
            drawnow

            system(command);
            pause(2.5);
            
            while runPython
                pause(0.5);
                
                try
                    msg = fileread(progressFile);
                catch
                    msg = "Python run error!";
                    runPython = false;
                end

                if app.processStop
                    msg = "Measurement aborted!";
                    runPython = false;
                end

                if contains(msg,'done')
                    msg = "Measurement done!";
                    runPython = false;
                    measMat = readtable(measurementFile);
                end

                app.StatusEditField.Value = msg;
                drawnow
            end

        end
        
        function measMat = readWaveformFile(app)
            [file, filepath] = uigetfile('*.csv',"Select Waveform CSV File");

            if isequal(file,0)
                return;
            end

            fullpath = strcat(filepath,file);

            try
               measMat = readtable(fullpath);
            catch
                errordlg("Waveform csv file hasn't been imported.")
               return;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.MainApp = mainapp;
            app.numAcq = 0;
        end

        % Close request function: AcquisitionDialogUIFigure
        function AcquisitionDialogUIFigureCloseRequest(app, event)
            % Enable Acquire button in main app, if the app is still open
            if isvalid(app.MainApp)
                app.MainApp.AcquirefromTeraSmartButton.Enable = "on";
                app.MainApp.DeployDataButton.Enable = "on";
                app.MainApp.ClearMemoryButton.Enable = "on";
            end

            % Delete the dialog box
            delete(app)            
        end

        % Button pushed function: ACQUIREButton
        function ACQUIREButtonPushed(app, event)
            app.SystemReadyLamp.Color = [0.85,0.33,0.10];
            app.SystemReadyLampLabel.Text = "Scaning...";
            app.processStop = false;
            fileLoad = false;
            fig = app.AcquisitionDialogUIFigure;
            drawnow

            addMeasurement(app,fileLoad);
            app.SystemReadyLamp.Color = "Green";
            app.SystemReadyLampLabel.Text = "Ready";
            drawnow         

            % Update Main App table
            updateTableRemote(app.MainApp,app.TcellNew);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.CurrentCountEditField.Value = 0;
            app.MeasurementCountEditField.Value = 1;
            app.TimesecEditField.Value = 0;
            app.TimeLeftsecEditField.Value = 0;
            app.processStop = true;
        end

        % Button pushed function: BaselineButton
        function BaselineButtonPushed(app, event)
            refOption = true;
            try
                measMat = readWaveform(app,refOption);
                timeAxis = table2array(measMat(1,2:end));
                eAmp = table2array(measMat(2,2:end));
                app.matBaseline = [timeAxis;eAmp];
            catch
                app.BaselineLamp.Color = [0.85,0.33,0.10];
                return
            end

            app.BaselineLamp.Color = "Green";

        end

        % Button pushed function: ReferenceButton
        function ReferenceButtonPushed(app, event)
            refOption = true;
            try
                measMat = readWaveform(app,refOption);
                timeAxis = table2array(measMat(1,2:end));
                eAmp = table2array(measMat(2,2:end));
                app.matReference = [timeAxis;eAmp];
            catch
                app.ReferenceLamp.Color = [0.85,0.33,0.10];
                return
            end

            app.ReferenceLamp.Color = "Green";
        end

        % Button pushed function: RemoveBaselineButton
        function RemoveBaselineButtonPushed(app, event)
            app.matBaseline = [];
            app.BaselineLamp.Color = [0.85,0.33,0.10];
        end

        % Button pushed function: RemoveReferenceButton
        function RemoveReferenceButtonPushed(app, event)
            app.matReference = [];
            app.ReferenceLamp.Color = [0.85,0.33,0.10];
        end

        % Value changed function: MultiscanSwitch
        function MultiscanSwitchValueChanged(app, event)
            value = app.MultiscanSwitch.Value;

            if isequal(value,'count')
                app.TimesecEditField.Editable = "off";
                app.MeasurementCountEditField.Editable = "on";
                app.MeasurementCountEditField.BackgroundColor = [0.302 0.7451 0.9333];
                app.TimesecEditField.BackgroundColor = [1,1,1];
            else % time mode
                app.TimesecEditField.Editable = "on";
                app.TimesecEditField.BackgroundColor = [0.302 0.7451 0.9333];
                app.MeasurementCountEditField.BackgroundColor = [1,1,1];
                app.MeasurementCountEditField.Editable = "off";

            end

            app.TimesecEditField.Value = 0;
            app.MeasurementCountEditField.Value = 1;
        end

        % Button pushed function: csvLOADButton
        function csvLOADButtonPushed(app, event)
            fileLoad = true;
            app.StatusEditField.Value = "Loading waveform from csv file";
            addMeasurement(app,fileLoad);
            drawnow;

            % Update Main App table
            app.StatusEditField.Value = "Updating the main table";
            updateTableRemote(app.MainApp,app.TcellNew);
            drawnow;
            app.StatusEditField.Value = "The main table is updated!";
        end

        % Button pushed function: csvOPENButton
        function csvOPENButtonPushed(app, event)
            csvFileName = 'measurements.csv';
            try
                winopen(csvFileName);
            catch
                errordlg('"measurements.csv" is not found in the default folder.');
                return;
            end            
        end

        % Button pushed function: RESETAVGButton
        function RESETAVGButtonPushed(app, event)
            app.AverageNumberEditField.Value = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AcquisitionDialogUIFigure = uifigure('Visible', 'off');
            app.AcquisitionDialogUIFigure.Name = 'TeraSmart Control';
            app.AcquisitionDialogUIFigure.Icon = fullfile(pathToMLAPP, 'Images', 'CaT_logo.png');
            app.AcquisitionDialogUIFigure.CloseRequestFcn = createCallbackFcn(app, @AcquisitionDialogUIFigureCloseRequest, true);
            app.StatusButton = uibutton(app.AcquisitionDialogUIFigure, 'push');
            app.StatusButton.Text = 'Status';
            app.StatusEditField = uieditfield(app.AcquisitionDialogUIFigure, 'text');
            app.StatusEditField.Editable = 'off';
            app.MeasurementSettingsPanel = uipanel(app.AcquisitionDialogUIFigure);
            app.MeasurementSettingsPanel.Title = 'Measurement Settings';
            app.MultiscanSwitchLabel = uilabel(app.MeasurementSettingsPanel);
            app.MultiscanSwitchLabel.Text = 'Multi-scan';
            app.MultiscanSwitch = uiswitch(app.MeasurementSettingsPanel, 'slider');
            app.MultiscanSwitch.Items = {'count', 'time'};
            app.MultiscanSwitch.ValueChangedFcn = createCallbackFcn(app, @MultiscanSwitchValueChanged, true);
            app.MultiscanSwitch.Value = 'count';
            app.MeasurementCountEditFieldLabel = uilabel(app.MeasurementSettingsPanel);
            app.MeasurementCountEditFieldLabel.Text = 'Measurement Count';
            app.MeasurementCountEditField = uieditfield(app.MeasurementSettingsPanel, 'numeric');
            app.MeasurementCountEditField.Limits = [0 100000];
            app.MeasurementCountEditField.ValueDisplayFormat = '%.0f';
            app.MeasurementCountEditField.Value = 1;
            app.IntervalsecLabel = uilabel(app.MeasurementSettingsPanel);
            app.IntervalsecLabel.Text = 'Average Number';
            app.AverageNumberEditField = uieditfield(app.MeasurementSettingsPanel, 'numeric');
            app.AverageNumberEditField.Limits = [0 10000];
            app.AverageNumberEditField.ValueDisplayFormat = '%.0f';
            app.AverageNumberEditField.Value = 1;
            app.TimesecEditFieldLabel = uilabel(app.MeasurementSettingsPanel);
            app.TimesecEditFieldLabel.Text = 'Time (sec)';
            app.TimesecEditField = uieditfield(app.MeasurementSettingsPanel, 'numeric');
            app.TimesecEditField.Limits = [0 100000];
            app.TimesecEditField.ValueDisplayFormat = '%.0f';
            app.TimesecEditField.Editable = 'off';
            app.ReferenceAcquisitionPanel = uipanel(app.AcquisitionDialogUIFigure);
            app.ReferenceAcquisitionPanel.Title = 'Reference Acquisition';
            app.SubtractBaselineCheckBox = uicheckbox(app.ReferenceAcquisitionPanel);
            app.SubtractBaselineCheckBox.Text = 'Subtract Baseline';
            app.BaselineLampLabel = uilabel(app.ReferenceAcquisitionPanel);
            app.BaselineLampLabel.Text = 'Baseline';
            app.BaselineLamp = uilamp(app.ReferenceAcquisitionPanel);
            app.BaselineButton = uibutton(app.ReferenceAcquisitionPanel, 'push');
            app.BaselineButton.ButtonPushedFcn = createCallbackFcn(app, @BaselineButtonPushed, true);
            app.BaselineButton.Text = 'Baseline';
            app.RemoveBaselineButton = uibutton(app.ReferenceAcquisitionPanel, 'push');
            app.RemoveBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBaselineButtonPushed, true);
            app.RemoveBaselineButton.Text = 'Remove Baseline';
            app.ReferenceLampLabel = uilabel(app.ReferenceAcquisitionPanel);
            app.ReferenceLampLabel.Text = 'Reference';
            app.ReferenceLamp = uilamp(app.ReferenceAcquisitionPanel);
            app.ReferenceButton = uibutton(app.ReferenceAcquisitionPanel, 'push');
            app.ReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceButtonPushed, true);
            app.ReferenceButton.Text = 'Reference';
            app.RemoveReferenceButton = uibutton(app.ReferenceAcquisitionPanel, 'push');
            app.RemoveReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveReferenceButtonPushed, true);
            app.RemoveReferenceButton.Text = 'Remove Reference';
            app.MeasurementDetailsPanel = uipanel(app.AcquisitionDialogUIFigure);
            app.MeasurementDetailsPanel.Title = 'Measurement Details';
            app.SampleEditFieldLabel = uilabel(app.MeasurementDetailsPanel);
            app.SampleEditFieldLabel.Text = 'Sample';
            app.SampleEditField = uieditfield(app.MeasurementDetailsPanel, 'text');
            app.ModeDropDownLabel = uilabel(app.MeasurementDetailsPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.MeasurementDetailsPanel);
            app.ModeDropDown.Items = {'TX', 'RX'};
            app.ModeDropDown.Value = 'TX';
            app.DescriptionEditFieldLabel = uilabel(app.MeasurementDetailsPanel);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.MeasurementDetailsPanel, 'text');
            app.Metadata1EditFieldLabel = uilabel(app.MeasurementDetailsPanel);
            app.Metadata1EditFieldLabel.Text = 'Metadata 1';
            app.Metadata1EditField = uieditfield(app.MeasurementDetailsPanel, 'text');
            app.NumericValueEditField_2Label = uilabel(app.MeasurementDetailsPanel);
            app.NumericValueEditField_2Label.Text = 'Numeric Value';
            app.NumericValueEditField_1 = uieditfield(app.MeasurementDetailsPanel, 'numeric');
            app.Metadata2EditFieldLabel = uilabel(app.MeasurementDetailsPanel);
            app.Metadata2EditFieldLabel.Text = 'Metadata 2';
            app.Metadata2EditField = uieditfield(app.MeasurementDetailsPanel, 'text');
            app.NumericValueEditFieldLabel = uilabel(app.MeasurementDetailsPanel);
            app.NumericValueEditFieldLabel.Text = 'Numeric Value';
            app.NumericValueEditField_2 = uieditfield(app.MeasurementDetailsPanel, 'numeric');
            app.ACQUIREButton = uibutton(app.MeasurementDetailsPanel, 'push');
            app.ACQUIREButton.ButtonPushedFcn = createCallbackFcn(app, @ACQUIREButtonPushed, true);
            app.ACQUIREButton.Text = 'ACQUIRE';
            app.STOPButton = uibutton(app.MeasurementDetailsPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.csvOPENButton = uibutton(app.MeasurementDetailsPanel, 'push');
            app.csvOPENButton.ButtonPushedFcn = createCallbackFcn(app, @csvOPENButtonPushed, true);
            app.csvOPENButton.Text = 'OPEN';
            app.TemporalMeasurementFileLabel = uilabel(app.MeasurementDetailsPanel);
            app.TemporalMeasurementFileLabel.Text = 'Temporal Measurement File';
            app.csvLOADButton = uibutton(app.MeasurementDetailsPanel, 'push');
            app.csvLOADButton.ButtonPushedFcn = createCallbackFcn(app, @csvLOADButtonPushed, true);
            app.csvLOADButton.Text = 'LOAD';
            app.RESETAVGButton = uibutton(app.MeasurementDetailsPanel, 'push');
            app.RESETAVGButton.ButtonPushedFcn = createCallbackFcn(app, @RESETAVGButtonPushed, true);
            app.RESETAVGButton.Text = 'RESET AVG';
            app.SystemReadyLamp = uilamp(app.AcquisitionDialogUIFigure);
            app.SystemReadyLampLabel = uilabel(app.AcquisitionDialogUIFigure);
            app.SystemReadyLampLabel.Text = 'System Ready';
            app.Image = uiimage(app.AcquisitionDialogUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'MENLO-Logo.png');
            % Show the figure after all components are created
            app.AcquisitionDialogUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AcquisitionDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AcquisitionDialogUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AcquisitionDialogUIFigure)
        end
    end
end